classdef get_data_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ConnectSensorsButton      matlab.ui.control.Button
        DisconnectSensorsButton   matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        StatusLampLabel           matlab.ui.control.Label
        StatusLamp                matlab.ui.control.Lamp
        GestureKnobLabel          matlab.ui.control.Label
        GestureKnob               matlab.ui.control.DiscreteKnob
        DetailsEditFieldLabel     matlab.ui.control.Label
        DetailsEditField          matlab.ui.control.EditField
        LilyDropDownLabel         matlab.ui.control.Label
        LilyDropDown              matlab.ui.control.DropDown
        COMDropDownLabel          matlab.ui.control.Label
        COMDropDown               matlab.ui.control.DropDown
        LilysLabel                matlab.ui.control.Label
        LilysSpinner              matlab.ui.control.Spinner
        UITable                   matlab.ui.control.Table
        TestModeSwitchLabel       matlab.ui.control.Label
        TestModeSwitch            matlab.ui.control.Switch
        FlexSforLulySpinnerLabel  matlab.ui.control.Label
        FlexSforLulySpinner       matlab.ui.control.Spinner
        IMUArmDropDownLabel       matlab.ui.control.Label
        IMUArmDropDown            matlab.ui.control.DropDown
        IMUBackDropDownLabel      matlab.ui.control.Label
        IMUBackDropDown           matlab.ui.control.DropDown
        GetPortsButton            matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
    end


    properties (Access = private)
        stopButtonPressed   % Description
    end

    methods (Access = private)
    
        function enableButtons(app)
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
        end
        
        function disableButtons(app)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
        end
        
        function enableConnections(app)
            app.IMUArmDropDown.Enable = "on";
            app.IMUBackDropDown.Enable = "on";
            app.GetPortsButton.Enable = "on";
            app.LilysSpinner.Enable = "on";
            app.FlexSforLulySpinner.Enable = "on";
            app.LilyDropDown.Enable = "on";
            app.COMDropDown.Enable = "on";
            app.UITable.Enable = "on";
        end
        
        function disableConnections(app)
            app.IMUArmDropDown.Enable = "off";
            app.IMUBackDropDown.Enable = "off";
            app.GetPortsButton.Enable = "off";
            app.LilysSpinner.Enable = "off";
            app.FlexSforLulySpinner.Enable = "off";
            app.LilyDropDown.Enable = "off";
            app.COMDropDown.Enable = "off";
            app.UITable.Enable = "off";
        end
    end


    methods (Access = private)

        % Button pushed function: ConnectSensorsButton
        function ConnectSensorsButtonPushed(app, event)
            if isempty(app.UITable.Data(:))
                app.Label.Text = 'Select # Lilys & PORTS';
                pause(1)
                app.Label.Text = '0';
                return
            end
            app.Label_2.Text = ['2 IMUS - ', int2str(app.LilysSpinner.Value),' Lilys - ',...
                (int2str(app.FlexSforLulySpinner.Value * app.LilysSpinner.Value)), ' FlexSensors'];
            disableConnections(app);
            app.ConnectSensorsButton.Enable = "off";
            app.TestModeSwitch.Enable = "off";
            app.DisconnectSensorsButton.Enable = "on";
            if app.TestModeSwitch.Value == "Off"
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
            end
            enableButtons(app);
            app.StopButton.Enable = "off";
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: DisconnectSensorsButton
        function DisconnectSensorsButtonPushed(app, event)
            enableConnections(app);
            app.ConnectSensorsButton.Enable = "on";
            app.TestModeSwitch.Enable = "on";
            app.DisconnectSensorsButton.Enable = "off";
%             if app.TestModeSwitch.Value == "Off"
%                 if (app.lpSensorArm.disconnect() && app.lpSensorBack.disconnect())
%                     disp('Sensor disconnected')
%                 end
%             end
            app.Label_2.Text = "";
            disableButtons(app)
            app.StatusLamp.Color = 'r';
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.stopButtonPressed = true;
            app.StartButton.Enable = "off";
            app.ConnectSensorsButton.Enable = "off";
            app.DisconnectSensorsButton.Enable = "off";
            app.StatusLamp.Color = 'y';
            if app.TestModeSwitch.Value == "Off" 
                app.Label.Text = "Synchronizing...";
                
                % principal parameters
                numLilys = app.LilysSpinner.Value;          % number of Ard connected
                nSens = app.FlexSforLulySpinner.Value;      % number of sensors including zero from each Lily
                nCount = 1;                                 % initial sample
                flagIMUData = false;                        % flag to know when IMUs Syncs
                readingFlag = false;                         % reading sensors flag
                %assignin('base','Data',app.UITable.Data)
                
                % COM port cleaning
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                
                % Arduinos
                disp('Arduinos ...')
                %ardPorts = [{'COM7'} {'COM5'} {'COM8'} {'COM9'} {'COM10'}];
                for i=1:numLilys
                    arduinos(i,:) = serial(app.UITable.Data(i,2),'BaudRate',9600);
                    fopen(arduinos(i));
                    flushinput(arduinos(i));
                end
                
                %% IMU SENSORS
                disp('IMU sensors ...')
                %IMUPorts = [{'COM3'} {'COM4'}]; % COM Ports to which IMUs are connected
                baudrate = 921600;              % rate at which information is transferred
                lpSensor1 = lpms();             % object lpms API sensor 1 given by LPMS
                lpSensor2 = lpms();             % object lpms API sensor 2 given by LPMS
                if ( ~lpSensor1.connect(app.IMUArmDropDown.Value, baudrate) || ~lpSensor2.connect(app.IMUBackDropDown.Value, baudrate) )
                    disp('Sensors not connected')
                    return 
                end
                lpSensor1.setStreamingMode();
                lpSensor2.setStreamingMode();
                disp('All set up, starting reading ...')
                
                %% format serial incoming data
                formatID = '';
                for nSen = 1: nSens
                    if nSen < nSens
                        formatID = [formatID , '%f,'];
                    else
                        formatID = [formatID , '%f'];
                    end
                end

                app.StatusLamp.Color = 'c';
                app.Label.Text = "0";
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% READING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % reading
                app.StopButton.Enable = "on";
                now = tic;
                while app.stopButtonPressed
                    dataIMU1 = lpSensor1.getQueueSensorData();        % get queue IMU1 sensor data
                    dataIMU2 = lpSensor2.getQueueSensorData();        % get queue IMU2 sensor data
                    
                    % Sync Method
                    if (~isempty(dataIMU1)) && (~isempty(dataIMU2))
                        flagIMUData = true;
                    elseif (~isempty(dataIMU1)) && (isempty(dataIMU2))
                        while (isempty(dataIMU2))
                            dataIMU2 = lpSensor2.getQueueSensorData();
                        end
                        flagIMUData = true;
                    elseif (~isempty(dataIMU2)) && (isempty(dataIMU1))
                        while (isempty(dataIMU1))
                            dataIMU1 = lpSensor1.getQueueSensorData();
                        end
                        flagIMUData = true;
                    elseif (isempty(dataIMU2)) && (isempty(dataIMU1))
                        flagIMUData = false;
                    end
                    % when IMUs are Sync, store data on variables
                    if flagIMUData
                        Sample(nCount,:) = nCount;
                        clockT(nCount,:) = clock;
                        dataIMUs(nCount,:) = [dataIMU1.timestamp dataIMU1.quat dataIMU1.acc ...
                                              dataIMU2.timestamp dataIMU2.quat dataIMU2.acc];
                        try
                            startingData = 1;
                            for i=1:numLilys
                                dataArd(nCount,startingData:1:(nSens*i)) = fscanf(arduinos(i),formatID);
                                startingData = startingData + nSens;
                            end
                            app.Label.Text = int2str(nCount);
                            nCount = nCount + 1;
                        catch
                            disp('Serial Arduino Data error')
                        end
                        flagIMUData = false;
                    end
                    if nCount > 10 && readingFlag == false
                        app.StatusLamp.Color = 'b';
                        readingFlag = true;
                    end
                    drawnow
                end
                time = toc(now);
                speed = nCount/time;
                fprintf('Count: %d time: %f speed: %f\n',nCount, time, speed);
                app.Label.Text = ['Speed: ', int2str(speed), ' samples/s'];
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Storing data seccion %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% preparing vectors
                Hour = clockT(:,4);
                Min = clockT(:,5);
                Sec = clockT(:,6);
                Data = [Sample Hour Min Sec dataIMUs dataArd];
                
                % formatting
                
                format1 = '%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s'; 
                format2 = '%12.0f%12d%12d%12.3f%12.2f%12.6f%12.6f%12.6f%12.6f%12.5f%12.5f%12.5f%12.2f%12.6f%12.6f%12.6f%12.6f%12.5f%12.5f%12.5f';
                numData = 16+1+3+(nSens*numLilys); % 16 IMUs + 1 Sample + 3 Clock + N SensFlex
                str = strings([1,(numData)]);
                str(1) = 'Sample'; str(2) = 'Hour'; str(3) = 'Minute'; str(4) = 'Sec';
                str(5) = 'Stamp'; str(6) = 'Quat1'; str(7) = 'Quat2'; str(8) = 'Quat3'; str(9) = 'Quat4';
                str(10) = 'AccX'; str(11) = 'AccY'; str(12) = 'AccZ';
                str(13) = 'Stamp2'; str(14) = 'Quat2_1'; str(15) = 'Quat2_2'; str(16) = 'Quat2_3'; str(17) = 'Quat2_4';
                str(18) = 'Acc2_X'; str(19) = 'Acc2_Y'; str(20) = 'Acc2_Z';
                for nSen = 1: (nSens*numLilys)
                    if nSen < (nSens*numLilys)
                        format1 = [format1 , '%12s'];
                        format2 = [format2 , '%12.2f'];
                        str(nSen+(numData-(nSens*numLilys))) = ['A', int2str(nSen-1)];
                    else
                        format1 = [format1 , '%12s\r\n'];
                        format2 = [format2 , '%12.2f\r\n'];
                        str(nSen+(numData-(nSens*numLilys))) = ['A', int2str(nSen-1)];
                    end
                end
                
                % directory
                folderName = 'testsData';
                [status, msg, msgID] = mkdir(folderName);
                dir = [pwd, '\',folderName];
                editFieldText = app.DetailsEditField.Value;
                gesture = app.GestureKnob.Value;
                S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmssSSS'));
                fileName = [editFieldText, '_', gesture, '_', S,'.txt'];
                
                % writing file
                fileID = fopen(fullfile(dir,fileName),'wt');
                fprintf(fileID, format1, str); % col names
                fprintf(fileID, format2, Data');
                fclose(fileID);
                
                % saving workspace
                assignin('base','Data',Data)
                fileName = [editFieldText, '_', gesture, '_', S,'.mat'];
                save(fileName,'Data')
                
                %% disconnecting
                disp('Disconnecting')
                clear arduinos
                if (lpSensor1.disconnect() && lpSensor2.disconnect())
                    disp('Sensors disconnected')
                end
                disp('Done')
            end
            pause(0.5)
            app.StatusLamp.Color = 'g';
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";
            app.DisconnectSensorsButton.Enable = "on";
            app.Label.Text = '0';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopButtonPressed = false;
            app.Label.Text = "0";
            app.StartButton.Enable = "on";
        end

        % Value changed function: LilysSpinner
        function LilysSpinnerValueChanged(app, event)
            value = app.LilysSpinner.Value;
            list = "";
            for i=1:value
                newElement = ['Lily', int2str(i)];
                list(i,:) = {newElement};
                Name(i,:) = {newElement};
                COM(i,:) = {"COM"};
            end
            app.LilyDropDown.Items = list;
            T = [Name, COM];
            app.UITable.Data = cellstr(T);
            app.COMDropDown.Items = seriallist;
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            if ~isempty(app.UITable.Data(:))
                COM = {app.COMDropDown.Value};
                LilyNum = app.LilyDropDown.Value;
                num = app.LilysSpinner.Value;
                for i=1:num
                    this = app.UITable.Data(i,1);
                    if this{1} == LilyNum
                        app.UITable.Data(i,2) = COM;
                    end
                end
            end
            % disp(app.UITable.Data)
            % assignin('base','myVar',app.UITable.Data)
        end

        % Button pushed function: GetPortsButton
        function GetPortsButtonPushed(app, event)
            app.COMDropDown.Items = seriallist;
            app.IMUArmDropDown.Items = seriallist;
            app.IMUBackDropDown.Items = seriallist;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ConnectSensorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSensorsButtonPushed, true);
            app.ConnectSensorsButton.Text = 'Connect Sensors';
            app.DisconnectSensorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSensorsButtonPushed, true);
            app.DisconnectSensorsButton.Enable = 'off';
            app.DisconnectSensorsButton.Text = 'Disconnect Sensors';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.GestureKnobLabel = uilabel(app.UIFigure);
            app.GestureKnobLabel.Text = 'Gesture';
            app.GestureKnob = uiknob(app.UIFigure, 'discrete');
            app.GestureKnob.Items = {'Green_Side', 'Green_Fron', 'Blue_F', 'Purple_F', 'Black_F', 'Purp_INV_F', 'Black_INV_F'};
            app.GestureKnob.Value = 'Green_Side';
            app.DetailsEditFieldLabel = uilabel(app.UIFigure);
            app.DetailsEditFieldLabel.Text = 'Details:';
            app.DetailsEditField = uieditfield(app.UIFigure, 'text');
            app.DetailsEditField.Value = 'Test_22_03';
            app.LilyDropDownLabel = uilabel(app.UIFigure);
            app.LilyDropDownLabel.Text = 'Lily';
            app.LilyDropDown = uidropdown(app.UIFigure);
            app.LilyDropDown.Items = {'Lily1'};
            app.LilyDropDown.Value = 'Lily1';
            app.COMDropDownLabel = uilabel(app.UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.UIFigure);
            app.COMDropDown.Items = {'Select Port...'};
            app.COMDropDown.Editable = 'on';
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'Select Port...';
            app.LilysLabel = uilabel(app.UIFigure);
            app.LilysLabel.Text = '# Lilys';
            app.LilysSpinner = uispinner(app.UIFigure);
            app.LilysSpinner.Limits = [1 6];
            app.LilysSpinner.RoundFractionalValues = 'on';
            app.LilysSpinner.ValueDisplayFormat = '%.0f';
            app.LilysSpinner.ValueChangedFcn = createCallbackFcn(app, @LilysSpinnerValueChanged, true);
            app.LilysSpinner.Value = 6;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lilys'; 'COM'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.TestModeSwitchLabel = uilabel(app.UIFigure);
            app.TestModeSwitchLabel.Text = 'Test Mode';
            app.TestModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.FlexSforLulySpinnerLabel = uilabel(app.UIFigure);
            app.FlexSforLulySpinnerLabel.Text = 'FlexS for Luly';
            app.FlexSforLulySpinner = uispinner(app.UIFigure);
            app.FlexSforLulySpinner.Limits = [1 10];
            app.FlexSforLulySpinner.RoundFractionalValues = 'on';
            app.FlexSforLulySpinner.ValueDisplayFormat = '%.0f';
            app.FlexSforLulySpinner.Value = 5;
            app.IMUArmDropDownLabel = uilabel(app.UIFigure);
            app.IMUArmDropDownLabel.Text = 'IMU Arm';
            app.IMUArmDropDown = uidropdown(app.UIFigure);
            app.IMUArmDropDown.Items = {'Select Port...'};
            app.IMUArmDropDown.Editable = 'on';
            app.IMUArmDropDown.Value = 'Select Port...';
            app.IMUBackDropDownLabel = uilabel(app.UIFigure);
            app.IMUBackDropDownLabel.Text = 'IMU  Back';
            app.IMUBackDropDown = uidropdown(app.UIFigure);
            app.IMUBackDropDown.Items = {'Select Port...'};
            app.IMUBackDropDown.Editable = 'on';
            app.IMUBackDropDown.Value = 'Select Port...';
            app.GetPortsButton = uibutton(app.UIFigure, 'push');
            app.GetPortsButton.ButtonPushedFcn = createCallbackFcn(app, @GetPortsButtonPushed, true);
            app.GetPortsButton.Text = 'Get Ports';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = get_data_Test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end